classdef racetrackSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TheskidzonewasestablishedbytheradiusLabel  matlab.ui.control.Label
        Accelerationms2TextArea         matlab.ui.control.TextArea
        Accelerationms2TextAreaLabel    matlab.ui.control.Label
        SpeedmsTextArea                 matlab.ui.control.TextArea
        SpeedmsTextAreaLabel            matlab.ui.control.Label
        TimeelapsedTextArea             matlab.ui.control.TextArea
        TimeelapsedTextAreaLabel        matlab.ui.control.Label
        EQUIPO5NOVIEMBRE2022Label       matlab.ui.control.Label
        DistancetraveledTextArea        matlab.ui.control.TextArea
        DISPLAYSIMULATIONWITHDRIFTCASE2Button  matlab.ui.control.Button
        DistanciarecorridaLabel         matlab.ui.control.Label
        ENABLEDISABLEGRIDButton         matlab.ui.control.Button
        YaxispositionTextArea           matlab.ui.control.TextArea
        YaxispositionTextAreaLabel      matlab.ui.control.Label
        XaxispositionTextArea           matlab.ui.control.TextArea
        XaxispositionTextAreaLabel      matlab.ui.control.Label
        DISPLAYSIMULATIONWITHOUTDRIFTButton  matlab.ui.control.Button
        DISPLAYSIMULATIONWITHDRIFTCASE1Button  matlab.ui.control.Button
        DISPLAYBLEACHERSANDGRAPHButton  matlab.ui.control.Button
        fx002150410354x31966856061x24459864268x191950757610x50Label  matlab.ui.control.Label
        PolynomialfunctionthatmodelstheracetrackEnglishVersionLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DISPLAYBLEACHERSANDGRAPHButton
        function DISPLAYBLEACHERSANDGRAPHButtonPushed(app, event)
            %Graficar el polinomio
            cla(app.UIAxes)
            xgraf = linspace(10,50,200);
            hx1grafrx1 = linspace(11.455,13.474, 20);
            px1grafqx1 = linspace(9.437, 11.455, 20);
            hx2grafrx2 = linspace(42.061,44.279, 20);
            px2grafqx2 = linspace(39.843,42.061, 20);
            xgraf2 = 12.83:0.1:17.37;
            xgraf3 = 43.6:0.1:48.14;
            xgraf4 = 12.83:0.1:20;
            xgraf5 = 43.6:0.1:50;
            ygraf = 0.02150410354.*xgraf.^3 - 1.966856061.*xgraf.^2 + 44.59864268.*xgraf + 19.19507576;
            ygraf2 = 0.02150410354.*xgraf2.^3 - 1.966856061.*xgraf2.^2 + 44.59864268.*xgraf2 + 19.19507576;
            ygraf3 = 0.02150410354.*xgraf3.^3 - 1.966856061.*xgraf3.^2 + 44.59864268.*xgraf3 + 19.19507576;
            tan1 = 4.743176833294.*xgraf4+252.19390435805;
            hx1 = 4.743176833294.*hx1grafrx1 + 259;
            px1 = 4.743176833294.*px1grafqx1 + 269;
            qx1 = (-1/4.743176833294)*px1grafqx1 + 315.75;
            rx1 = (-1/4.743176833294).*hx1grafrx1 + 325.75;

            tan2 = -4.274361205522.*xgraf5+193.44335977638;
            hx2 = -4.274361205522.*hx2grafrx2 + 186.5;
            px2 = -4.274361205522.*px2grafqx2 + 176.5;
            qx2 = (1/4.274361205522).*px2grafqx2 -3.124;
            rx2 = (1/4.274361205522).*hx2grafrx2 -13.124;

            plot(app.UIAxes, xgraf, ygraf, 'Color', 'k', 'LineWidth', 1.5);
            hold (app.UIAxes, "on")
            plot(app.UIAxes, xgraf2, ygraf2, 'Color', 'red', 'LineWidth', 2)
            hold (app.UIAxes, "on")
            plot(app.UIAxes, xgraf3, ygraf3, 'Color', 'red', 'LineWidth', 2)
            hold (app.UIAxes, "on")
            plot(app.UIAxes, xgraf4, tan1, 'Color', 'cyan', 'LineWidth', 1)
            hold (app.UIAxes, "on")
            plot(app.UIAxes, xgraf5, tan2, 'Color', 'cyan', 'LineWidth', 1)
            hold (app.UIAxes, "on")
            plot(app.UIAxes, hx1grafrx1, hx1, 'Color', 'magenta', 'LineWidth', 2)
            hold (app.UIAxes, "on")
            plot(app.UIAxes, hx1grafrx1, rx1, 'Color', 'magenta', 'LineWidth', 2)
            hold (app.UIAxes, "on")
            plot(app.UIAxes, px1grafqx1, px1, 'Color', 'magenta', 'LineWidth', 2)
            hold (app.UIAxes, "on")
            plot(app.UIAxes, px1grafqx1, qx1, 'Color', 'magenta', 'LineWidth', 2)
            hold (app.UIAxes, "on")
            plot(app.UIAxes, hx2grafrx2, hx2, 'Color', 'magenta', 'LineWidth', 2)
            hold (app.UIAxes, "on")
            plot(app.UIAxes, px2grafqx2, px2, 'Color', 'magenta', 'LineWidth', 2)
            hold (app.UIAxes, "on")
            plot(app.UIAxes, px2grafqx2, qx2, 'Color', 'magenta', 'LineWidth', 2)
            hold (app.UIAxes, "on")
            plot(app.UIAxes, hx2grafrx2, rx2, 'Color', 'magenta', 'LineWidth', 2)
            xlim(app.UIAxes, [9,50])
        end

        % Button pushed function: DISPLAYSIMULATIONWITHOUTDRIFTButton
        function DISPLAYSIMULATIONWITHOUTDRIFTButtonPushed(app, event)
            %Graficar el polinomio
            xgraf = linspace(10,50,200);

            ygraf = 0.02150410354.*xgraf.^3 - 1.966856061.*xgraf.^2 + 44.59864268.*xgraf + 19.19507576;
            funciondistancia = @(var) (1+(0.0645123106.*var.^2-3.933712122.*var+44.59864268).^2).^0.5;
            i = 1;
            cla(app.UIAxes)
            drecorrida = 0;
            tiempo = 0;
            velocidad = 0;
            aceleracion = 0;
            while i <= length(xgraf)
                hold (app.UIAxes, "on")
                plot(app.UIAxes,xgraf, ygraf, '-k');
                hold (app.UIAxes, "on")
                plot(app.UIAxes,xgraf(i), ygraf(i), 'ob', 'LineWidth', 4, 'MarkerSize', 4)
                drecorrida_1 = drecorrida;
                velocidad_1 = velocidad;
                drecorrida = integral(funciondistancia,10,xgraf(i));
                app.XaxispositionTextArea.Value = string(xgraf(i));
                app.YaxispositionTextArea.Value = string(ygraf(i));
                app.DistancetraveledTextArea.Value = string(drecorrida);
                app.TimeelapsedTextArea.Value = string(tiempo);
                app.SpeedmsTextArea.Value = string(velocidad);
                app.Accelerationms2TextArea.Value = string(aceleracion);
                velocidad = (drecorrida-drecorrida_1)/0.05;
                aceleracion = (velocidad-velocidad_1)/0.05;
                tiempo = tiempo + 0.05;
                i = i+1;
                pause(0.035);
                cla(app.UIAxes)
            end
        end

        % Button pushed function: ENABLEDISABLEGRIDButton
        function ENABLEDISABLEGRIDButtonPushed(app, event)
            grid(app.UIAxes,"minor")
        end

        % Button pushed function: DISPLAYSIMULATIONWITHDRIFTCASE1Button
        function DISPLAYSIMULATIONWITHDRIFTCASE1ButtonPushed(app, event)
            xgraf = linspace(10,50,100);
            recorrido = linspace(10,20,50);
            ygraf = 0.02150410354.*xgraf.^3 - 1.966856061.*xgraf.^2 + 44.59864268.*xgraf + 19.19507576;
            recorridoy = 0.02150410354.*recorrido.^3 - 1.966856061.*recorrido.^2 + 44.59864268.*recorrido + 19.19507576;
            tan1 = 4.743176833294.*recorrido+252.19390435805;
            i = 1;
            funciondistancia = @(var) (1+(0.0645123106.*var.^2-3.933712122.*var+44.59864268).^2).^0.5;
            cla(app.UIAxes)
            drecorrida = 0;
            tiempo = 0;
            velocidad = 0;
            aceleracion = 0;
            while i <= length(recorrido)
                if recorrido(i) <= 12.83
                    hold (app.UIAxes, "on")
                    plot(app.UIAxes,xgraf, ygraf, 'k');
                    plot(app.UIAxes,12.83, 313.06, 'or', 'LineWidth', 3.5, 'MarkerSize', 4);
                    hold (app.UIAxes, "on")
                    plot(app.UIAxes,recorrido(i), recorridoy(i), 'ob', 'LineWidth', 4, 'MarkerSize', 4)
                    drecorrida_1 = drecorrida;
                    velocidad_1 = velocidad;
                    drecorrida = integral(funciondistancia,10,xgraf(i));
                    app.XaxispositionTextArea.Value = string(recorrido(i));
                    app.YaxispositionTextArea.Value = string(recorridoy(i));
                    app.DistancetraveledTextArea.Value = string(drecorrida);
                    app.TimeelapsedTextArea.Value = string(tiempo);
                    app.SpeedmsTextArea.Value = string(velocidad);
                    app.Accelerationms2TextArea.Value = string(aceleracion);
                    velocidad = (drecorrida-drecorrida_1)/0.1;
                    aceleracion = (velocidad-velocidad_1)/0.1;
                    tiempo = tiempo + 0.1;
                    i = i+1;
                    pause(0.075);
                    cla(app.UIAxes)
                else
                    hold (app.UIAxes, "on")
                    plot(app.UIAxes,xgraf, ygraf, '-k');
                    hold (app.UIAxes, "on")
                    plot(app.UIAxes,recorrido(i), tan1(i), 'ob', 'LineWidth', 4, 'MarkerSize', 4)
                    app.XaxispositionTextArea.Value = string(recorrido(i));
                    drecorrida = integral(funciondistancia,10,12.83) + 4.84743*recorrido(i)-4.84743*12.83;
                    app.YaxispositionTextArea.Value = string(tan1(i));
                    app.DistancetraveledTextArea.Value = string(drecorrida);
                    app.TimeelapsedTextArea.Value = string(tiempo);
                    app.SpeedmsTextArea.Value = "Out of road";
                    app.Accelerationms2TextArea.Value = "Out of road";
                    tiempo = tiempo + 0.1;
                    i = i+1;
                    pause(0.075);
                    cla(app.UIAxes)
                end
            end
            figure = uifigure(app.UIFigure);
            uialert(figure, 'The car has skidded at the point (12.83, 313.06) and has left the race track. Send the paramedics immediately!', 'DANGER!!!')
        end

        % Button pushed function: DISPLAYSIMULATIONWITHDRIFTCASE2Button
        function DISPLAYSIMULATIONWITHDRIFTCASE2ButtonPushed(app, event)
            xgraf = linspace(10,50,100);
            ygraf = 0.02150410354.*xgraf.^3 - 1.966856061.*xgraf.^2 + 44.59864268.*xgraf + 19.19507576;
            tan2 = -4.274361205522.*xgraf+193.44335977638;
            funciondistancia = @(var) (1+(0.0645123106.*var.^2-3.933712122.*var+44.59864268).^2).^0.5;
            i = 1;
            cla(app.UIAxes)
            drecorrida = 0;
            tiempo = 0;
            velocidad = 0;
            aceleracion = 0;
            while i <= length(xgraf)
                if xgraf(i) <= 43.6
                    hold (app.UIAxes, "on")
                    plot(app.UIAxes,xgraf, ygraf, 'k');
                    plot(app.UIAxes,43.6, 7.07, 'or', 'LineWidth', 3.5, 'MarkerSize', 4);
                    hold (app.UIAxes, "on")
                    plot(app.UIAxes,xgraf(i), ygraf(i), 'ob', 'LineWidth', 4, 'MarkerSize', 4)
                    drecorrida_1 = drecorrida;
                    velocidad_1 = velocidad;
                    drecorrida = integral(funciondistancia,10,xgraf(i));
                    calor = 1000*velocidad*velocidad/2;
                    app.XaxispositionTextArea.Value = string(xgraf(i));
                    app.YaxispositionTextArea.Value = string(ygraf(i));
                    app.DistancetraveledTextArea.Value = string(drecorrida);
                    app.TimeelapsedTextArea.Value = string(tiempo);
                    app.SpeedmsTextArea.Value = string(velocidad);
                    app.Accelerationms2TextArea.Value = string(aceleracion);
                    velocidad = (drecorrida-drecorrida_1)/0.1;
                    aceleracion = (velocidad-velocidad_1)/0.1;
                    tiempo = tiempo + 0.1;
                    i = i+1;
                    pause(0.075);
                    cla(app.UIAxes)
                else
                    hold (app.UIAxes, "on")
                    plot(app.UIAxes,xgraf, ygraf, '-k');
                    hold (app.UIAxes, "on")
                    plot(app.UIAxes,xgraf(i), tan2(i), 'ob', 'LineWidth', 4, 'MarkerSize', 4)
                    drecorrida = integral(funciondistancia,10,43.6) + 4.38977*xgraf(i) - 4.38977*43.6;
                    app.XaxispositionTextArea.Value = string(xgraf(i));
                    app.YaxispositionTextArea.Value = string(tan2(i));
                    app.DistancetraveledTextArea.Value = string(drecorrida);
                    app.SpeedmsTextArea.Value = "Out of road";
                    app.Accelerationms2TextArea.Value = "Out of road";
                    i = i+1;
                    pause(0.075);
                    cla(app.UIAxes)
                end
            end
            figure = uifigure(app.UIFigure);
            uialert(figure, 'The car has skidded at the point (43.6, 7.07) and has left the race track. Send the paramedics immediately!', 'DANGER!!!')
        end

        % Callback function
        function BorrarButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.Accelerationms2TextArea)
            cla(app.SpeedmsTextArea)
            cla(app.XaxispositionTextArea)
            cla(app.YaxispositionTextArea)
            cla(app.DistancetraveledTextArea)
            cla(app.HeatlossTextArea)
            cla(app.TimeelapsedTextArea)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Race track')
            xlabel(app.UIAxes, 'X axis')
            ylabel(app.UIAxes, 'Y axis')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'lines')
            app.PolynomialfunctionthatmodelstheracetrackEnglishVersionLabel = uilabel(app.UIFigure);
            app.PolynomialfunctionthatmodelstheracetrackEnglishVersionLabel.Interpreter = 'latex';
            app.PolynomialfunctionthatmodelstheracetrackEnglishVersionLabel.Text = 'Polynomial function that models the racetrack (English Version)';
            app.fx002150410354x31966856061x24459864268x191950757610x50Label = uilabel(app.UIFigure);
            app.fx002150410354x31966856061x24459864268x191950757610x50Label.Interpreter = 'tex';
            app.fx002150410354x31966856061x24459864268x191950757610x50Label.Text = 'f(x) = 0.02150410354x^3 - 1.966856061x^2 + 44.59864268x + 19.19507576  [10<x<50]';
            app.DISPLAYBLEACHERSANDGRAPHButton = uibutton(app.UIFigure, 'push');
            app.DISPLAYBLEACHERSANDGRAPHButton.ButtonPushedFcn = createCallbackFcn(app, @DISPLAYBLEACHERSANDGRAPHButtonPushed, true);
            app.DISPLAYBLEACHERSANDGRAPHButton.Text = 'DISPLAY BLEACHERS AND GRAPH';
            app.DISPLAYSIMULATIONWITHDRIFTCASE1Button = uibutton(app.UIFigure, 'push');
            app.DISPLAYSIMULATIONWITHDRIFTCASE1Button.ButtonPushedFcn = createCallbackFcn(app, @DISPLAYSIMULATIONWITHDRIFTCASE1ButtonPushed, true);
            app.DISPLAYSIMULATIONWITHDRIFTCASE1Button.Text = 'DISPLAY SIMULATION WITH DRIFT CASE 1';
            app.DISPLAYSIMULATIONWITHOUTDRIFTButton = uibutton(app.UIFigure, 'push');
            app.DISPLAYSIMULATIONWITHOUTDRIFTButton.ButtonPushedFcn = createCallbackFcn(app, @DISPLAYSIMULATIONWITHOUTDRIFTButtonPushed, true);
            app.DISPLAYSIMULATIONWITHOUTDRIFTButton.Text = 'DISPLAY SIMULATION WITHOUT DRIFT';
            app.XaxispositionTextAreaLabel = uilabel(app.UIFigure);
            app.XaxispositionTextAreaLabel.Text = 'X axis position';
            app.XaxispositionTextArea = uitextarea(app.UIFigure);
            app.YaxispositionTextAreaLabel = uilabel(app.UIFigure);
            app.YaxispositionTextAreaLabel.Text = 'Y axis position';
            app.YaxispositionTextArea = uitextarea(app.UIFigure);
            app.ENABLEDISABLEGRIDButton = uibutton(app.UIFigure, 'push');
            app.ENABLEDISABLEGRIDButton.ButtonPushedFcn = createCallbackFcn(app, @ENABLEDISABLEGRIDButtonPushed, true);
            app.ENABLEDISABLEGRIDButton.Text = 'ENABLE / DISABLE GRID';
            app.DistanciarecorridaLabel = uilabel(app.UIFigure);
            app.DistanciarecorridaLabel.Text = {'Distance'; 'traveled'};
            app.DISPLAYSIMULATIONWITHDRIFTCASE2Button = uibutton(app.UIFigure, 'push');
            app.DISPLAYSIMULATIONWITHDRIFTCASE2Button.ButtonPushedFcn = createCallbackFcn(app, @DISPLAYSIMULATIONWITHDRIFTCASE2ButtonPushed, true);
            app.DISPLAYSIMULATIONWITHDRIFTCASE2Button.Text = 'DISPLAY SIMULATION WITH DRIFT CASE 2';
            app.DistancetraveledTextArea = uitextarea(app.UIFigure);
            app.EQUIPO5NOVIEMBRE2022Label = uilabel(app.UIFigure);
            app.EQUIPO5NOVIEMBRE2022Label.Text = {'MADE BY TEAM 5 (NOVEMBER 2022)'; 'UPDATED (JANUARY 2023) BY IMANOL'; ''; 'Imanol Armando González Solís'; 'Pedro Gabriel Sánchez Valdez'; 'Diego Hilario López Rodríguez'; 'Enrique Macías Lopez'};
            app.TimeelapsedTextAreaLabel = uilabel(app.UIFigure);
            app.TimeelapsedTextAreaLabel.Text = {'Time'; 'elapsed'};
            app.TimeelapsedTextArea = uitextarea(app.UIFigure);
            app.SpeedmsTextAreaLabel = uilabel(app.UIFigure);
            app.SpeedmsTextAreaLabel.Text = {'Speed'; 'm/s'};
            app.SpeedmsTextArea = uitextarea(app.UIFigure);
            app.Accelerationms2TextAreaLabel = uilabel(app.UIFigure);
            app.Accelerationms2TextAreaLabel.Text = {'Acceleration'; 'm/s^2'};
            app.Accelerationms2TextArea = uitextarea(app.UIFigure);
            app.TheskidzonewasestablishedbytheradiusLabel = uilabel(app.UIFigure);
            app.TheskidzonewasestablishedbytheradiusLabel.Text = {'Note: The skid zone was established by the radius of'; 'curvature, see documentation for more information'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = racetrackSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end